classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        GridLayout     matlab.ui.container.GridLayout
        LeftPanel      matlab.ui.container.Panel
        Label          matlab.ui.control.Label
        Slider         matlab.ui.control.Slider
        Slider_2Label  matlab.ui.control.Label
        Slider_2       matlab.ui.control.Slider
        text10         matlab.ui.control.Label
        edit4          matlab.ui.control.EditField
        edit6          matlab.ui.control.EditField
        TextAreaLabel  matlab.ui.control.Label
        TextArea       matlab.ui.control.TextArea
        RightPanel     matlab.ui.container.Panel
        UIAxes         matlab.ui.control.UIAxes
        UIAxes2        matlab.ui.control.UIAxes
        UIAxes3        matlab.ui.control.UIAxes
        text1          matlab.ui.control.Label
        text2          matlab.ui.control.Label
        text3          matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: edit4
        function edit4ValueChanged(app, event)
            Value = app.edit4.Value;
              [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                global T;global N;global t;
                    input = str2double(get(hObject,'String'));
                    T=input;
                %时域波形图

                t=-2*pi/10:pi/1024:2*pi/10;
                ft=square(2*pi*30*t,5*T);
                subplot(1,3,1),plot(app.UIAxes,t,ft), axis(app.UIAxes,[-5,5,-5,5])
            
                %频谱图
                m=-30:30;      %区别于吉布斯
                w1=2*pi/T;
                fn=1./(pi*m);
                subplot(1,3,2),stem(app.UIAxes2,m*w1,fn), axis(app.UIAxes2,[-15,15,-0.4,0.4])
            
            
                %吉布斯
                n_max=N;  %得到最大谐波次数
                    n=1:1:n_max; %定义矩阵n
                    an=4./(n*pi).*sin(double(n*pi*T/10));%得到an，即基波分量的系数
                    w=cos(n'*2*pi*(t-T/20));    %将n转置，得到w矩阵
                    x=an*w+T/5-1;  %两矩阵相乘，得到对应图像
                    subplot(1,3,3),plot(app.UIAxes3,t,x);    %画出相应图像
                 axis(app.UIAxes3,[-5,5,0,20])%设置坐标轴范围


                set(handles.Slider,'Value',T)  %滑动条与输入框一致
     app.edit4.ValueChangedFcn = get(hObject,'String');   %得到输入的字符串
                set(handles.edit4,'string','输入');
        end

        % Value changed function: edit6
        function edit6ValueChanged(app, event)

            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                global T;global N;global t;
                        input = str2num(get(hObject,'String'));
                    N=input;
                %时域波形图
                t=-10:0.002:10;
                ft=square(2*pi*t,T);
                subplot(1,3,1),plot(app.UIAxes,t,ft), axis(app.UIAxes,[-5,5,-5,5])
            
                %频谱图
                m=-30:30;      %区别于吉布斯
                w1=2*pi/T;
                fn=1./(pi*m);
                subplot(1,3,2),stem(app.UIAxes2,m*w1,fn), axis(app.UIAxes2,[-15,15,-0.4,0.4])
            
            
                %吉布斯
                n_max=N;  %得到最大谐波次数
                    n=1:1:n_max; %定义矩阵n
                    an=4./(n*pi).*sin(double(n*pi*T/10));%得到an，即基波分量的系数
                    w=cos(n'*2*pi*(t-T/20));  %将n转置，得到w矩阵
                    x=an*w+T/5-1;  %两矩阵相乘，得到对应图像
                     subplot(1,3,3),plot(app.UIAxes3,t,x);    %画出相应图像
                 axis(app.UIAxes3,[-5,5,0,20])%设置坐标轴范围
            
            
                set(handles.Slider_2,'Value',N)  %滑动条与输入框一致
                app.edit6.ValueChangedFcn = get(hObject,'String');   %得到输入的字符串
                set(handles.edit6,'string','输入');
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
                 
                   % Create GUIDE-style callback args - Added by Migration Tool
             [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); 
            
                global T;global N;global t;
           guidata(hObject,handles);


                input = get(hObject,'Value');
                T= input;
                %时域波形图
                t=-10:0.002:10;
                ft=square(2*pi*t,T);
                subplot(1,3,1),plot(app.UIAxes,t,ft), axis(app.UIAxes,[-5,5,-5,5])

                %频谱图
                m=-30:30;      %区别于吉布斯
                w1=2*pi/T;
                fn=1./(pi*m);
                subplot(1,3,2),stem(app.UIAxes2,m*w1,fn), axis(app.UIAxes2,[-15,15,-0.4,0.4])
            

                %吉布斯
                n_max=N;  %得到最大谐波次数
                    n=1:1:n_max; %定义矩阵n
                    an=4./(n*pi).*sin(double(n*pi*T/10));%得到an，即基波分量的系数
                    w=cos(n'*2*pi*(t-T/20));    %将n转置，得到w矩阵
                    x=an*w+T/5-1;  %两矩阵相乘，得到对应图像
                   subplot(1,3,3), plot(app.UIAxes3,t,x);    %画出相应图像
                 axis(app.UIAxes3,[-5,5,0,20])%设置坐标轴范围
            
            
                set(handles.edit4,'String',num2str(T)); %使得输入框edit1和滑动条保持一致
                 app.Slider.Value = get(hObject,'Value');  %得到滑动条的值
  
        end

        % Value changing function: Slider_2
        function Slider_2ValueChanging(app, event)
            changingValue = event.Value;
              [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                global T;global N;global t;  
                guidata(hObject,handles);
                   input = get(hObject,'Value');
                   
                N= input;
                %时域波形图
                t=-10:0.002:10;
                ft=square(2*pi*t,T);
                subplot(1,3,1),plot(app.UIAxes,t,ft), axis(app.UIAxes,[-5,5,-5,5])
            
                %频谱图
                m=-30:30;      %区别于吉布斯
                w1=2*pi/T;
                fn=1./(pi*m);
                subplot(1,3,2),stem(app.UIAxes2,m*w1,fn), axis(app.UIAxes2,[-15,15,-0.4,0.4])
            
            
                %吉布斯
                n_max=N;  %得到最大谐波次数
                    n=1:1:n_max; %定义矩阵n
                    an=4./(n*pi).*sin(double(n*pi*T/10));%得到an，即基波分量的系数
                    w=cos(n'*2*pi*(t-T/20));    %将n转置，得到w矩阵
                    x=an*w+T/5-1;  %两矩阵相乘，得到对应图像
                   subplot(1,3,3), plot(app.UIAxes3,t,x);    %画出相应图像
                 axis(app.UIAxes3,[-5,5,0,20])%设置坐标轴范围


                set(handles.edit6,'String',num2str(N)); %使得输入框edit2和滑动条保持一致
                 app.Slider_2.Value = get(hObject,'Value');  %得到滑动条的值
    
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {575, 575};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {317, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '周期';
            app.Slider = uislider(app.LeftPanel);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider_2Label = uilabel(app.LeftPanel);
            app.Slider_2Label.Text = '最大谐波次数';
            app.Slider_2 = uislider(app.LeftPanel);
            app.Slider_2.ValueChangingFcn = createCallbackFcn(app, @Slider_2ValueChanging, true);
            app.text10 = uilabel(app.LeftPanel);
            app.text10.Tag = 'text10';
            app.text10.Text = '方波';
            app.edit4 = uieditfield(app.LeftPanel, 'text');
            app.edit4.ValueChangedFcn = createCallbackFcn(app, @edit4ValueChanged, true);
            app.edit4.Tag = 'edit4';
            app.edit6 = uieditfield(app.LeftPanel, 'text');
            app.edit6.ValueChangedFcn = createCallbackFcn(app, @edit6ValueChanged, true);
            app.edit6.Tag = 'edit6';
            app.TextAreaLabel = uilabel(app.LeftPanel);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.LeftPanel);
            app.TextArea.Value = {'bug:会弹出一个空白figure，滑动条为随机值'};
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.RightPanel);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.text1 = uilabel(app.RightPanel);
            app.text1.Tag = 'text1';
            app.text1.Text = '时域波形图';
            app.text2 = uilabel(app.RightPanel);
            app.text2.Tag = 'text2';
            app.text2.Text = '频谱图';
            app.text3 = uilabel(app.RightPanel);
            app.text3.Tag = 'text3';
            app.text3.Text = '吉布斯现象';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end